classdef BloodCellDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        REPORTButton         matlab.ui.control.Button
        QUITButton           matlab.ui.control.Button
        Panel_3              matlab.ui.container.Panel
        RBCEditField         matlab.ui.control.NumericEditField
        RBCEditFieldLabel    matlab.ui.control.Label
        WBCEditField         matlab.ui.control.NumericEditField
        WBCEditFieldLabel    matlab.ui.control.Label
        CounterButton        matlab.ui.control.Button
        Panel_2              matlab.ui.container.Panel
        PreprocesingButton   matlab.ui.control.Button
        CellDetectionButton  matlab.ui.control.Button
        EditDetectionButton  matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        SetthispropertieswhenchooseROILabel  matlab.ui.control.Label
        YmaxEditField        matlab.ui.control.NumericEditField
        YmaxEditFieldLabel   matlab.ui.control.Label
        YminEditField        matlab.ui.control.NumericEditField
        YminEditFieldLabel   matlab.ui.control.Label
        XmaxEditField        matlab.ui.control.NumericEditField
        XmaxEditFieldLabel   matlab.ui.control.Label
        XminEditField        matlab.ui.control.NumericEditField
        XminEditFieldLabel   matlab.ui.control.Label
        ChooseROIButton      matlab.ui.control.Button
        LoadImageButton      matlab.ui.control.Button
        BloodDetectorfrommicroscopsphotoLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        myClass         % class MicroscopeBloodImage
        window2         % handle to window 2 - pre procesing image
        ax21            % handle to axes in window 2
        ax22            % handle to axes in window 2
        ax23            % handle to axes in window 2
        ax24            % handle to axes in window 2
        window3         % handle to window 3 - detected image
        ax31            % handle to axes in window 3
        ax32            % handle to axes in window 3
        ax33            % handle to axes in window 3
        window4         % handle to window 4 - detected image
        ax41            % handle to axes in window 4
        step1           % infomration image was loading
        step2           % infomration about pre procesing
        step3           % information about detection
        xmin            % minimum x value of Roi
        xmax            % maximum x value of Roi
        ymin            % minimum y value of Roi
        ymax            % maximum y value of Roi
        window5         % handle to window 5 - report
        ax51            % handle to axes in window 5
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app.myClass = RoiMicroscopeBloodImage;
            app.myClass.loadImage;
            imshow(app.myClass.rawImage,"Parent",app.UIAxes);
            if isempty(app.myClass.rawImage)
                return
            else
                [row,column, ~] = size(app.myClass.rawImage);
                xlim(app.UIAxes,[1,column]);
                ylim(app.UIAxes, [1,row]);

                app.step1 = 1;
            end
        end

        % Button pushed function: PreprocesingButton
        function PreprocesingButtonPushed(app, event)
            if app.step1 ==1

                app.myClass.preProcesingImage;

                if ~isempty(app.window2)
                    delete(app.window2)
                    app.window2 = [];
                end

                app.window2 = uifigure;
                app.window2.AutoResizeChildren = 'off';

                app.ax21 = subplot(2,2,1,'Parent',app.window2);
                imshow(app.myClass.grayScaleImage,"Parent",app.ax21)
                title(app.ax21, "Gray scale Image")
                app.ax22 = subplot(2,2,2,'Parent',app.window2);
                imshow(app.myClass.contrastImage,"Parent",app.ax22)
                title(app.ax22,"Contrast Image")
                app.ax23 = subplot(2,2,3,'Parent',app.window2);
                imshow(app.myClass.binaryImage,"Parent",app.ax23)
                title(app.ax23, "Binarize Image")
                app.ax24 = subplot(2,2,4,'Parent',app.window2);
                imshow(app.myClass.postProcesingImage,"Parent",app.ax24)
                title(app.ax24, "Negative Eroded Image","without small objects")

                app.step2 = 1;
            else
                msgbox("First load image!", 'Error', 'error')
            end
        end

        % Button pushed function: CellDetectionButton
        function CellDetectionButtonPushed(app, event)
            if  app.step2 == 1
                app.myClass.cellDetector;

                if ~isempty(app.window3)
                    delete(app.window3)
                    app.window3 = [];
                end

                app.window3 = uifigure();
                app.window3.AutoResizeChildren = 'off';

                app.ax31 = subplot(2,3,1,'Parent', app.window3);
                imshow(app.myClass.maskWBC, "Parent", app.ax31);
                title(app.ax31, 'WBC mask');

                app.ax32 = subplot(2,3,4,'Parent', app.window3);
                imshow(app.myClass.maskRBC, "Parent", app.ax32);
                title(app.ax32, 'RBC mask');

                app.ax33 = subplot(1,3,[2,3], 'Parent', app.window3);
                imshow(app.myClass.rawImage, 'Parent', app.ax33);

                hold(app.ax33, 'on')
                h = imshow(app.myClass.cellWBC,'Parent', app.ax33);
                set(h,'AlphaData',app.myClass.rimWBC)
                h1 = imshow(app.myClass.cellRBC,'Parent', app.ax33);
                set(h1, 'AlphaData', app.myClass.rimRBC)
                title(app.ax33,'Auto Cell Detecion')

                % wybranie idneksow z krwinkami białymi
                idx = app.myClass.pointsIDX(:,3) == 119;
                WBC = app.myClass.pointsIDX(idx,:);

                % wybranie indeksow z krwinkami czerwonymi
                idx = app.myClass.pointsIDX(:,3) == 114;
                RBC = app.myClass.pointsIDX(idx,:);

                plot(app.ax33,RBC(:,1), RBC(:,2),'r*');
                plot(app.ax33,WBC(:,1), WBC(:,2),'w*');
                app.step3=1;
            else
                msgbox("First do preprocesing!", 'Error', 'error')
            end


        end

        % Button pushed function: EditDetectionButton
        function EditDetectionButtonPushed(app, event)
            if app.step2 == 1

                app.myClass.editDetection

                if ~isempty(app.window4)
                    delete(app.window4)
                    app.window4 = [];
                end

                app.window4 = uifigure();
                app.ax41 = axes("Parent",app.window4);
                imshow(app.myClass.rawImage, 'Parent', app.ax41);
                hold(app.ax41, 'on')
                title(app.ax41,'Manual Cell Detecion');

                % wybranie idneksow z krwinkami białymi
                idx = app.myClass.pointsIDX(:,3) == 119;
                WBC = app.myClass.pointsIDX(idx,:);

                % wybranie indeksow z krwinkami czerwonymi
                idx = app.myClass.pointsIDX(:,3) == 114;
                RBC = app.myClass.pointsIDX(idx,:);

                plot(app.ax41,RBC(:,1), RBC(:,2),'r*');
                plot(app.ax41,WBC(:,1), WBC(:,2),'w*');
                app.step3 = 1
            else
                msgbox("First do preprocesing!", 'Error', 'error')
            end
        end

        % Button pushed function: CounterButton
        function CounterButtonPushed(app, event)
            if app.step3 == 1
                app.myClass.counter

                app.RBCEditField.Value = app.myClass.countRBC;
                app.WBCEditField.Value = app.myClass.countWBC;
            else
                msgbox("First do detection!")
            end
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            close("all","force")
        end

        % Button pushed function: ChooseROIButton
        function ChooseROIButtonPushed(app, event)
            if app.step1 == 1
                if app.xmin < app.xmax && app.ymin < app.ymax
                    app.myClass.ROI(app.xmin,app.xmax ,app.ymin,app.ymax);
                    imshow(app.myClass.rawImage,"Parent",app.UIAxes);
                    [row,column, ~] = size(app.myClass.rawImage);
                    xlim(app.UIAxes,[1,column]);
                    ylim(app.UIAxes, [1,row]);
                else
                    msgbox("Min value must be lower than max!", 'Error', 'error')
                end
            else
                msgbox("First load image!", 'Error', 'error')
            end
        end

        % Value changed function: XminEditField
        function XminEditFieldValueChanged(app, event)
            value = app.XminEditField.Value;
            app.xmin = value;

        end

        % Value changed function: XmaxEditField
        function XmaxEditFieldValueChanged(app, event)
            value = app.XmaxEditField.Value;
            app.xmax = value;
        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            value = app.YminEditField.Value;
            app.ymin = value;
        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            value = app.YmaxEditField.Value;
            app.ymax = value;
        end

        % Button pushed function: REPORTButton
        function REPORTButtonPushed(app, event)
            if app.step3 == 1
           
            app.window5 = figure('visible','off');
            app.ax51 = axes("Parent",app.window5);
            imshow(app.myClass.rawImage, 'Parent', app.ax51);
            hold(app.ax51, 'on')

            % wybranie idneksow z krwinkami białymi
            idx = app.myClass.pointsIDX(:,3) == 119;
            WBC = app.myClass.pointsIDX(idx,:);

            % wybranie indeksow z krwinkami czerwonymi
            idx = app.myClass.pointsIDX(:,3) == 114;
            RBC = app.myClass.pointsIDX(idx,:);

            plot(app.ax51,RBC(:,1), RBC(:,2),'r*');
            plot(app.ax51,WBC(:,1), WBC(:,2),'w*');
            title(app.ax51,'Cell detection number of:',...
                ['WBC = ', num2str(height(WBC)),' RBC = ', num2str(height(RBC))])
            legend(app.ax51, "RBC marker", "WBC marker",'Color','b')

            direction = app.myClass.getPathFile();
            
            if isempty(direction)
                return
            else
                saveas(app.window5,direction);
                delete(app.ax51);
                delete(app.window5);
                
                msgbox("Generete report end!", 'Succes')
            end
            else
                msgbox("First you have to do all steps!", 'Error', 'error')
            end
                
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Image')
            zlabel(app.UIAxes, 'Z')
            app.BloodDetectorfrommicroscopsphotoLabel = uilabel(app.UIFigure);
            app.BloodDetectorfrommicroscopsphotoLabel.Text = {'Blood Detector from '; 'microscop''s photo'};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '#1';
            app.LoadImageButton = uibutton(app.Panel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ChooseROIButton = uibutton(app.Panel, 'push');
            app.ChooseROIButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseROIButtonPushed, true);
            app.ChooseROIButton.Text = 'Choose ROI';
            app.XminEditFieldLabel = uilabel(app.Panel);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.Panel, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XminEditFieldValueChanged, true);
            app.XmaxEditFieldLabel = uilabel(app.Panel);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.Panel, 'numeric');
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxEditFieldValueChanged, true);
            app.YminEditFieldLabel = uilabel(app.Panel);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.Panel, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YmaxEditFieldLabel = uilabel(app.Panel);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.Panel, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.SetthispropertieswhenchooseROILabel = uilabel(app.Panel);
            app.SetthispropertieswhenchooseROILabel.Text = 'Set this properties when choose ROI.';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '#2';
            app.EditDetectionButton = uibutton(app.Panel_2, 'push');
            app.EditDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EditDetectionButtonPushed, true);
            app.EditDetectionButton.Text = 'Edit Detection';
            app.CellDetectionButton = uibutton(app.Panel_2, 'push');
            app.CellDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CellDetectionButtonPushed, true);
            app.CellDetectionButton.Text = 'Cell Detection';
            app.PreprocesingButton = uibutton(app.Panel_2, 'push');
            app.PreprocesingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocesingButtonPushed, true);
            app.PreprocesingButton.Text = 'Preprocesing';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '#3';
            app.CounterButton = uibutton(app.Panel_3, 'push');
            app.CounterButton.ButtonPushedFcn = createCallbackFcn(app, @CounterButtonPushed, true);
            app.CounterButton.Text = 'Counter';
            app.WBCEditFieldLabel = uilabel(app.Panel_3);
            app.WBCEditFieldLabel.Text = 'WBC';
            app.WBCEditField = uieditfield(app.Panel_3, 'numeric');
            app.RBCEditFieldLabel = uilabel(app.Panel_3);
            app.RBCEditFieldLabel.Text = 'RBC';
            app.RBCEditField = uieditfield(app.Panel_3, 'numeric');
            app.QUITButton = uibutton(app.UIFigure, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.REPORTButton = uibutton(app.UIFigure, 'push');
            app.REPORTButton.ButtonPushedFcn = createCallbackFcn(app, @REPORTButtonPushed, true);
            app.REPORTButton.Text = 'REPORT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BloodCellDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end